classdef appMaquina01MNSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        RelatrioMenu                 matlab.ui.container.Menu
        PginaXptoMenu                matlab.ui.container.Menu
        SairMenu                     matlab.ui.container.Menu
        SobreMenu                    matlab.ui.container.Menu
        AutoresMenu                  matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SistemaDinamicoDropDown      matlab.ui.control.DropDown
        SistemaDinmicoDropDownLabel  matlab.ui.control.Label
        Lamp                         matlab.ui.control.Lamp
        LampLabel                    matlab.ui.control.Label
        GrelhaCheckBox               matlab.ui.control.CheckBox
        ResetButton                  matlab.ui.control.Button
        ExecutarButton               matlab.ui.control.Button
        bgMNumericos                 matlab.ui.container.ButtonGroup
        TodososMetodosButton         matlab.ui.control.ToggleButton
        AdamButton                   matlab.ui.control.ToggleButton
        ODE45Button                  matlab.ui.control.ToggleButton
        RK4Button                    matlab.ui.control.ToggleButton
        RK2Button                    matlab.ui.control.ToggleButton
        EulerMButton                 matlab.ui.control.ToggleButton
        EulerButton                  matlab.ui.control.ToggleButton
        DadosdoPVIPanel              matlab.ui.container.Panel
        nSpinner                     matlab.ui.control.Spinner
        nSpinnerLabel                matlab.ui.control.Label
        eV0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_4            matlab.ui.control.Label
        eG                           matlab.ui.control.EditField
        ftuvLabel_2                  matlab.ui.control.Label
        eU0                          matlab.ui.control.NumericEditField
        aEditFieldLabel_3            matlab.ui.control.Label
        eB                           matlab.ui.control.NumericEditField
        aEditFieldLabel_2            matlab.ui.control.Label
        eA                           matlab.ui.control.NumericEditField
        aEditFieldLabel              matlab.ui.control.Label
        eF                           matlab.ui.control.EditField
        ftuvLabel                    matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        UITable                      matlab.ui.control.Table
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ExecutarButtonPushed(app, [])
        end

        % Callback function: ExecutarButton, bgMNumericos, nSpinner
        function ExecutarButtonPushed(app, event)
            syms u(t) v(t)
            strF = app.eF.Value;
            f =@(t,u,v) eval(vectorize(strF));
            %f = matlabFunction(str2sym(strF));
            strG = app.eG.Value;
            g =@(t,u,v) eval(vectorize(strG));

            a = app.eA.Value;
            b = app.eB.Value;
            n = app.nSpinner.Value;
            u0 = app.eU0.Value;
            v0 = app.eV0.Value;
            
            MNumericoSelecionado = app.bgMNumericos.SelectedObject.Text;
            
            erroFuncao = MException("MATLAB:Executar:badF",...
                                    "Introduza uma função em t, u e v");
            erroB = MException("MATLAB:Executar:badB",...
                                    "Introduza um b > a");

            try
                try
                    z = g(t,u,v);
                    app.eF.BackgroundColor = "w";
                catch
                    app.eF.BackgroundColor = [1,0,0];
                    throw(erroFuncao)
                end

                if b<=a
                    app.eB.BackgroundColor = "r";
                    throw(erroB)
                else
                    app.eB.BackgroundColor = [1,1,1];
                end

                SED = [diff(u,t)==f(t,u,v), diff(v,t)==g(t,u,v)];
                ci = [u(a)==u0, v(a)==v0];
               
               try
                    [uExata, ~] = dsolve(SED, ci);
                    h = matlabFunction(uExata);
                    uExata = h(t);
                catch
                    uExata = NaN(size(t));  % Define como NaN se não houver solução
                end

                % Chama os métodos numéricos
                [~, uEuler, ~] = NEulerSED(f, g, a, b, n, u0, v0);
                [~, uRK2, ~] = NRK2SED(f, g, a, b, n, u0, v0);
                [~, uRK4, ~] = NRK4SED(f, g, a, b, n, u0, v0);
                [~, uODE45, ~]   = ODE45(f, g, a, b, n, u0, v0);
                [~, uAB2SED, ~] = AB2SED(f, g, a, b, n, u0, v0);

                erroEuler = abs(uExata-uEuler);

                erroRK2 = abs(uExata-uRK2);
                erroRK4 = abs(uExata-uRK4);
                erroODE45 = abs(uExata-uODE45);
                erroAB2SED = abs(uExata-uAB2SED);
               
                plot(app.UIAxes,t,uExata,"-ro")
                hold(app.UIAxes,"on")
                
                erroMax = 0;
                switch MNumericoSelecionado
                    case "Euler"
                        if app.SistemaDinamicoDropDown.Value ~= "3" 
                            plot(app.UIAxes,t,uEuler,"-b*")
                            legendaGrafico = ["Exata","Euler"];
                            A = [t.',uExata.',uEuler.',erroEuler.'];
                            nomeColunasTabela = ["t","Exata","Euler","erroEuler"];
                            erroMax = max(erroEuler);
                        else
                            plot(app.UIAxes,t,uEuler,"-b*")
                            legendaGrafico = "Euler";
                            A = [t.',uEuler.',erroEuler.'];
                            nomeColunasTabela = ["t","Euler","erroEuler"];
                            erroMax = max(erroEuler);
                        end
                        
                    case "EulerM"
                        warndlg("Método não implementado","AVISO","modal");

                    case "RK2"
                        if app.SistemaDinamicoDropDown.Value ~= "3" 
                            plot(app.UIAxes,t,uRK2,"-g+")
                            legendaGrafico = ["Exata","RK2"];
                            A = [t.',uExata.',uRK2.',erroRK2.'];
                            nomeColunasTabela = ["t","Exata","RK2","erroRK2"];
                            erroMax = max(erroEuler);
                        else
                            plot(app.UIAxes,t,uRK2,"-g+")
                            legendaGrafico = ["Exata","RK2"];
                            A = [t.',uRK2.',erroRK2.'];
                            nomeColunasTabela = ["t","RK2","erroRK2"];
                            erroMax = max(erroRK2);
                        end

                   case "RK4"
                        if app.SistemaDinamicoDropDown.Value ~= "3" 
                            plot(app.UIAxes,t,uRK4,"-md")
                            legendaGrafico = ["Exata","RK4"];
                            A = [t.',uExata.',uRK4.',erroRK4.'];
                            nomeColunasTabela = ["t","Exata","RK4","erroRK4"];
                            erroMax = max(erroRK4);
                        else
                            plot(app.UIAxes,t,uRK4,"-md")
                            legendaGrafico = ["Exata","RK4"];
                            A = [t.',uRK4.',erroRK4.'];
                            nomeColunasTabela = ["t","RK4","erroRK4"];
                        end
                        
                    case "ODE45"
                        if app.SistemaDinamicoDropDown.Value ~= "3" 
                            plot(app.UIAxes,t,uODE45,"-kp")
                            legendaGrafico = ["Exata","ODE45"];
                            A = [t.',uExata.',uODE45.',erroODE45.'];
                            nomeColunasTabela = ["t","Exata","ODE45","erroODE45"];
                            erroMax = max(erroODE45);
                        else
                            plot(app.UIAxes,t,uODE45,"-kp")
                            legendaGrafico = ["Exata","ODE45"];
                            A = [t.',uODE45.',erroODE45.'];
                            nomeColunasTabela = ["t","ODE45","erroODE45"];
                             erroMax = max(erroODE45);
                        end
                        
                    case "Adam"
                        if app.SistemaDinamicoDropDown.Value ~= "3" 
                            plot(app.UIAxes,t,uAB2SED,"-yh")
                            legendaGrafico = ["Exata","Adams-Moulton"];
                            A = [t.',uExata.',uAB2SED.',erroAB2SED.'];
                            nomeColunasTabela = ["t","Exata","Adams-Moulton","erroAdam"];
                            erroMax = max(erroAB2SED);
                        else
                            plot(app.UIAxes,t,uAB2SED,"-yh")
                            legendaGrafico = ["Exata","Adams-Moulton"];
                            A = [t.',uAB2SED.',erroAB2SED.'];
                            nomeColunasTabela = ["t","Adams-Moulton","erroAdam"];
                            erroMax = max(erroAB2SED);
                        end
                        
                    case "Todos os Métodos"
                        if app.SistemaDinamicoDropDown.Value ~= "3" 
                            plot(app.UIAxes, t, uExata, '-ro', 'DisplayName', 'Exata');
                            hold(app.UIAxes, 'on');
                            plot(app.UIAxes, t, uEuler, '-g*', 'DisplayName', 'Euler');
                            plot(app.UIAxes, t, uRK2, '-bs', 'DisplayName', 'RK2');
                            plot(app.UIAxes, t, uRK4, '-md', 'DisplayName', 'RK4');
                            plot(app.UIAxes, t, uODE45, '-kp', 'DisplayName', 'ODE45');
                            plot(app.UIAxes, t, uAB2SED, '-yh', 'DisplayName', 'Adams-Moulton');
                            hold(app.UIAxes, 'off');
                            
                            legendaGrafico = ["Exata", "Euler", "RK2", "RK4", "ODE45", "Adams-Moulton"];
                            A = [t.', uExata.', uEuler.', erroEuler.', uRK2.', erroRK2.', uRK4.', erroRK4.', uODE45.', erroODE45.', uAB2SED.', erroAB2SED.'];
                            nomeColunasTabela = ["t", "uExata", "uEuler", "erroEuler", "uRK2", "erroRK2", "uRK4", "erroRK4", "uODE45", "erroODE45", "uAB2SED", "erroAB2SED"];
                            erroMax = max([max(erroEuler), max(erroRK2), max(erroRK4), max(erroODE45), max(erroAB2SED)]);
                        else
                            plot(app.UIAxes, t, uEuler, '-g*', 'DisplayName', 'Euler');
                            hold(app.UIAxes, 'on');
                            plot(app.UIAxes, t, uRK2, '-bs', 'DisplayName', 'RK2');
                            plot(app.UIAxes, t, uRK4, '-md', 'DisplayName', 'RK4');
                            plot(app.UIAxes, t, uODE45, '-kp', 'DisplayName', 'ODE45');
                            plot(app.UIAxes, t, uAB2SED, '-yh', 'DisplayName', 'Adams-Moulton');
                            hold(app.UIAxes, 'off');
                            
                            legendaGrafico = ["Euler", "RK2", "RK4", "ODE45", "Adams-Moulton"];
                            A = [t.', uEuler.', erroEuler.', uRK2.', erroRK2.', uRK4.', erroRK4.', uODE45.', erroODE45.', uAB2SED.', erroAB2SED.'];
                            nomeColunasTabela = ["t", "uEuler", "erroEuler", "uRK2", "erroRK2", "uRK4", "erroRK4", "uODE45", "erroODE45", "uAB2SED", "erroAB2SED"];
                            erroMax = max([max(erroEuler), max(erroRK2), max(erroRK4), max(erroODE45), max(erroAB2SED)]);
                        end
                end

                if erroMax > 1
                    app.Lamp.Color = 'r';
                else
                    app.Lamp.Color = 'g';
                end

                hold(app.UIAxes,"off")
                legend(app.UIAxes,legendaGrafico)
               
                tabelaResultados = array2table(A);
                app.UITable.Data = tabelaResultados;  
                app.UITable.ColumnName = nomeColunasTabela;

            catch Me
                errordlg(Me.message,"Erro nos dados do PVI","modal")
            end           

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % app.eF.Value = "y+exp(3*t)";
            % app.eA.Value = 1;
            % app.eB.Value = 1.5;
            % app.nSpinner.Value = 2;
            % app.eU0.Value = 2;
            ExecutarButtonPushed(app, event)
        end

        % Value changed function: GrelhaCheckBox
        function GrelhaCheckBoxValueChanged(app, event)
            value = app.GrelhaCheckBox.Value;
            if value
                grid(app.UIAxes,"on")
            else
                grid(app.UIAxes,"off")
            end

        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete(app)
            SairMenuSelected(app, event)
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open("ISEC LaTeX Template (LIC).pdf")
            winopen("Implementação das normas em MSWord_PT_EN_v2023_BSc.docx")
        end

        % Menu selected function: PginaXptoMenu
        function PginaXptoMenuSelected(app, event)
           web("www.isec.pt")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            appAutores
        end

        % Value changed function: SistemaDinamicoDropDown
        function SistemaDinamicoDropDownValueChanged(app, event)
            value = app.SistemaDinamicoDropDown.Value;
        
            strF = "v";
            strG = "-16*u";
            a = 0; b = 8; n = 100; u0 = 9; v0 = 0;

    
            try
                switch value
                    case "1"
                        % Mola-Massa s/ Amortecimento
                        strF = "v";
                        strG = "-16*u";
                        a = 0; b = 8; n = 100; u0 = 9; v0 = 0;
                        
                    case "2"
                        % Mola-Massa  c/ Amortecimento
        
                        strF = "v";
                        strG = "cos(t)-16*u-8*v";
                        a = 0; b = 20; n = 100; u0 = 0; v0 = 0;
                        
                    case "3"
                        % Circuito Elétrico
                        strF = "v";
                        strG = "-10*v-25*u";
                        a = 0; b = 2; n = 20; u0 = 0; v0 = -4;
                        
                    case "4"
                        % Pêndulo não-linear (sem solução analítica)
                        strF = "v";
                        strG = "-sin(u) - 0.3*v";
                        a = 0; b = 15; n = 500;
                        u0 = pi/4; v0 = 0.5;
                                                            
                    case "5"
                        strF = "v";
                        strG = "-0.5*v + 0.01*u*v";
                        a = 0; b = 50; n = 500;
                        u0 = 40;    % Exemplo: população inicial de presas
                        v0 = 9;     % Exemplo: população inicial de predadores
                 
                    case "6"  
                        strF = "v";
                        strG = "2*(1-u^2)*v - u";
                        a = 0; b = 30; n = 1000;
                        u0 = 1;     % Exemplo: pequena perturbação
                        v0 = 0;                   
                end
                
                % Atualiza a interface
                app.eF.Value = strF;
                app.eG.Value = strG;
                app.eA.Value = a; 
                app.eB.Value = b; 
                app.nSpinner.Value = n;
                app.eU0.Value = u0; 
                app.eV0.Value = v0;
                
                % Executa os cálculos
                ExecutarButtonPushed(app, event);
                
            catch ME
                errordlg(ME.message, 'Erro ao carregar sistema dinâmico', 'modal');
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {474, 474};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.RelatrioMenu = uimenu(app.FileMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.PginaXptoMenu = uimenu(app.FileMenu);
            app.PginaXptoMenu.MenuSelectedFcn = createCallbackFcn(app, @PginaXptoMenuSelected, true);
            app.PginaXptoMenu.Text = 'PáginaXpto';
            app.SairMenu = uimenu(app.FileMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosdoPVIPanel = uipanel(app.LeftPanel);
            app.DadosdoPVIPanel.Title = 'Dados do PVI';
            app.ftuvLabel = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel.Text = 'f(t,u,v)  =  ';
            app.eF = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aEditFieldLabel = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel.Text = 'a';
            app.eA = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.aEditFieldLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eB.Value = 8;
            app.aEditFieldLabel_3 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_3.Text = 'u0 =';
            app.eU0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.eU0.Value = 9;
            app.ftuvLabel_2 = uilabel(app.DadosdoPVIPanel);
            app.ftuvLabel_2.Text = 'g(t,u,v) =  ';
            app.eG = uieditfield(app.DadosdoPVIPanel, 'text');
            app.eG.Value = '-16*u';
            app.aEditFieldLabel_4 = uilabel(app.DadosdoPVIPanel);
            app.aEditFieldLabel_4.Text = 'v0 =';
            app.eV0 = uieditfield(app.DadosdoPVIPanel, 'numeric');
            app.nSpinnerLabel = uilabel(app.DadosdoPVIPanel);
            app.nSpinnerLabel.Text = 'n';
            app.nSpinner = uispinner(app.DadosdoPVIPanel);
            app.nSpinner.Limits = [1 Inf];
            app.nSpinner.ValueDisplayFormat = '%.0f';
            app.nSpinner.ValueChangedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.nSpinner.Value = 100;
            app.bgMNumericos = uibuttongroup(app.LeftPanel);
            app.bgMNumericos.Title = 'Métodos Numéricos';
            app.EulerButton = uitogglebutton(app.bgMNumericos);
            app.EulerButton.Text = 'Euler';
            app.EulerMButton = uitogglebutton(app.bgMNumericos);
            app.EulerMButton.Text = 'EulerM';
            app.RK2Button = uitogglebutton(app.bgMNumericos);
            app.RK2Button.Text = 'RK2';
            app.RK2Button.Value = true;
            app.RK4Button = uitogglebutton(app.bgMNumericos);
            app.RK4Button.Text = 'RK4';
            app.ODE45Button = uitogglebutton(app.bgMNumericos);
            app.ODE45Button.Text = 'ODE45';
            app.AdamButton = uitogglebutton(app.bgMNumericos);
            app.AdamButton.Text = 'Adam';
            app.TodososMetodosButton = uitogglebutton(app.bgMNumericos);
            app.TodososMetodosButton.Text = 'Todos os Métodos';
            app.ExecutarButton = uibutton(app.LeftPanel, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Text = 'Executar';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GrelhaCheckBox = uicheckbox(app.LeftPanel);
            app.GrelhaCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrelhaCheckBoxValueChanged, true);
            app.GrelhaCheckBox.Text = 'Grelha';
            app.LampLabel = uilabel(app.LeftPanel);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.LeftPanel);
            app.SistemaDinmicoDropDownLabel = uilabel(app.LeftPanel);
            app.SistemaDinmicoDropDownLabel.Text = 'Sistema Dinâmico:';
            app.SistemaDinamicoDropDown = uidropdown(app.LeftPanel);
            app.SistemaDinamicoDropDown.Items = {'Mola-Massa s/ Amortecimento', 'Mola-Massa  c/ Amortecimento', 'Circuito Elétrico', 'Pêndulo', 'Outro01', 'Outro02'};
            app.SistemaDinamicoDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.SistemaDinamicoDropDown.ValueChangedFcn = createCallbackFcn(app, @SistemaDinamicoDropDownValueChanged, true);
            app.SistemaDinamicoDropDown.Value = '1';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquina01MNSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end